classdef Swabbing_test_application < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        GridLayout           matlab.ui.container.GridLayout
        LeftPanel            matlab.ui.container.Panel
        DebugLabel           matlab.ui.control.Label
        DebugLabeltitle      matlab.ui.control.Label
        CreateNewTestButton  matlab.ui.control.Button
        TestIDLabel          matlab.ui.control.Label
        CenterPanel          matlab.ui.container.Panel
        TakepictureButton    matlab.ui.control.Button
        SavepictureButton    matlab.ui.control.Button
        CameraModelLabel     matlab.ui.control.Label
        ColorAX              matlab.ui.control.UIAxes
        ImgAx                matlab.ui.control.UIAxes
        RightPanel           matlab.ui.container.Panel
        saveForceFigButton   matlab.ui.control.Button
        ForceSensorAxes      matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
        twoPanelWidth = 768;
    end


    properties (Access = public)
        
     RsPipeLine realsense.pipeline % link to the realsense camera 
     ImgCellArray % Cell array holding the last take image by camera {full colour, red, green ,blue} each is technically stored as a full colour image but with some of the channels set to 0
    end
    
    properties (Access = private)
        currentTestDir % the path to the current tests directory (folder)
        currentTestName % Name of the directory in which the current tests data is being stored.
        dirExist = 0  % boolean value indicating if there exists a current test directory. d
        ImgCaptured = 0;
        forceSenseTimer
        device % Serial device 
        nb_layer = 1; %for force sensor (IDK man it was there so it's here)
        dt=[0 0];%array conataining 2 timestamps: [when request received; when request sent] 
        vec = zeros(256, (1));%array containing the force values
        cmd = 16;
        prevArray = zeros(16);

    end
    
    methods (Access = private)
        
        
        function [Img] = captureAndSplitColourImg(app)

            RsFrameSet = app.RsPipeLine.wait_for_frames();
           

            cf = RsFrameSet.get_color_frame(); % the pipeline reading portion of this is from intel Realsense example code 
            dd = cf.get_data();
            d = permute(reshape(dd',[3,cf.get_width(),cf.get_height()]),[3 2 1]);

            Img = cell(1, size(d,1)); %create cell array to hold images

            Img{1} = d; %save full colour image 

            rImgChannel = d; % save red image 
            rImgChannel(:,:,2) = 0;
            rImgChannel(:,:,3) = 0; % simply sets all other colour channels to zero
            Img{2} = rImgChannel;

            gImgChannel = d; % save green image 
            gImgChannel(:,:,1) = 0;
            gImgChannel(:,:,3) = 0;
            Img{3} = gImgChannel;

            bImgChannel = d; % save blue image 
            bImgChannel(:,:,1) = 0;
            bImgChannel(:,:,2) = 0;
            Img{4} = bImgChannel;

            app.ImgCaptured = 1; % updates flag so that rest of program knows image has been captured
        
            drawnow;
          
        end

        function [success,testDir, testName] = CreateNewTest(app)
            % creates new test Directory with the current data and time in
            % a file friendly format. It also updates the dir exist flag to
            % notify the rest of the program that a test has been made.
            dateTimeStr = string( datetime("now",'format','dd-MM-yy_HH.mm.ss'));
            dataFilename = append("TestAt ",dateTimeStr);
            dirname = append(string(directoryGet()),"/data/");
            [success ,~,~]=mkdir(append(dirname,dataFilename));
            testDir = append(dirname,dataFilename);
            testName = dataFilename;
            app.currentTestDir = testDir;
            app.currentTestName = testName; % use this as the name of the test file.
            app.dirExist = 1;
            
            app.TestIDLabel.Text = testName;
        end
        
        function forceSensorReaderPlotter(app,~,~) %


            n = 16; % size on the n*n array to measure
            
            write(app.device,app.cmd,"uint8");
      
            app.dt = read(app.device,2,"uint32");
            xlabel(app.ForceSensorAxes,string(app.dt));
      
            if(app.nb_layer==2)
                app.vec = read(app.device,2*n*n,"uint16");%get forces array
                array = reshape(app.vec,2*n,n); % reshape data
            else 
                app.vec = read(app.device,n*n,"uint16");
                array = (reshape(app.vec,n,n))';
            end 
            contourf(app.ForceSensorAxes,(array+app.prevArray)/2);

            app.prevArray = array;


        end
        
        function SaveForceFigure(app)
           
            if(app.dirExist == 1)
                exportgraphics(app.ForceSensorAxes, append(app.currentTestDir,"/ForceFig.png"));
          
            else 
                app.DebugLabel.Text = "Could not save figure";
            end
            
        end
    end
    
    methods (Access = public)
        
        function success  = SaveCurrentImg(app,Colour)
            % this function saves a specific or all colour channels of the
            % last taken image in the last created test directory.
           if app.dirExist == 1 && app.ImgCaptured % makes sure there is both image and directory to save
                if Colour == "all"
                    imwrite(app.ImgCellArray{1},append(app.currentTestDir,'/FullColourImage.png'));
                    success = 1;
                elseif Colour == "r"
                    imwrite(app.ImgCellArray{2},append(app.currentTestDir,'/RedImage.png'))
                    success = 1;
                elseif Colour == "g"
                    imwrite(app.ImgCellArray{3},append(app.currentTestDir,'/GreenImage.png'))
                    success = 1;
                elseif Colour == "b"
                    imwrite(app.ImgCellArray{3},append(app.currentTestDir,'/BlueImage.png'));
                    success = 1;
                else 
                    success = 0 ; 
                end
           else
               success = 0;
           end 
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
           
            app.device = serialport("COM11",500000); % connect to the arduino ( select the right path: COM1, COM2 ... on windows)
            pause(1)%wait a bit

            if(app.nb_layer==2)
                 app.cmd=app.cmd+0x80;
            end


            RsCtx = realsense.context();
            Devs = RsCtx.query_devices();


            if ( numel(Devs) < 1 )
                error ( 'Not found RealSense device' );
            end

            RsDev = Devs{1};
            cn = RsDev.get_info(realsense.camera_info.name);
            delete(RsDev);
            delete(RsCtx);
            app.CameraModelLabel.Text = sprintf('rs_capture_class < Camera : %s >',cn);
            app.RsPipeLine = realsense.pipeline();


            cnfg = realsense.config();
            cnfg.enable_stream(realsense.stream.color, 1280, 720, realsense.format.any_format, 6)
            
            app.RsPipeLine.start(cnfg);

            app.TakepictureButton.Enable = "off";
            for i = 1:30
                app.RsPipeLine.wait_for_frames();
            end 
            app.TakepictureButton.Enable = "on";


            app.CreateNewTest()

            captureAndSplitColourImg(app);
            app.ImgCellArray = captureAndSplitColourImg(app);
            imshow(app.ImgCellArray{1},'Parent',app.ImgAx);
            montage({app.ImgCellArray{2},app.ImgCellArray{3},app.ImgCellArray{4}}, 'Parent',app.ColorAX,'Size',[1 3]);

            app.forceSenseTimer  = timer("ExecutionMode","fixedRate","Period",0.1,"BusyMode","queue","TimerFcn",@(timer_obj, event)app.forceSensorReaderPlotter);
            
            if strcmp(app.forceSenseTimer.Running,"off")
               start(app.forceSenseTimer);
            end


        end

        % Button pushed function: TakepictureButton
        function TakepictureButtonPushed(app, event)
            app.TakepictureButton.Enable = "off";
            app.ImgCellArray = captureAndSplitColourImg(app);
           
            imshow(app.ImgCellArray{1},'Parent',app.ImgAx);
            montage({app.ImgCellArray{2},app.ImgCellArray{3},app.ImgCellArray{4}}, 'Parent',app.ColorAX,'Size',[1 3]);
            
            app.TakepictureButton.Enable = "on";
        end

        % Button pushed function: SavepictureButton
        function SavepictureButtonPushed(app, event)
            app.DebugLabel.Text = string(app.SaveCurrentImg("all"));
        end

        % Button pushed function: CreateNewTestButton
        function CreateNewTestButtonPushed(app, event)
            CreateNewTest(app);
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            stop(app.forceSenseTimer);
            delete(app)
            
        end

        % Button pushed function: saveForceFigButton
        function saveForceFigButtonPushed(app, event)
            app.saveForceFigButton.Enable = "off";
            app.SaveForceFigure();
            app.saveForceFigButton.Enable = "on";
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 3x1 grid
                app.GridLayout.RowHeight = {539, 539, 539};
                app.GridLayout.ColumnWidth = {'1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 1;
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 3;
                app.RightPanel.Layout.Column = 1;
            elseif (currentFigureWidth > app.onePanelWidth && currentFigureWidth <= app.twoPanelWidth)
                % Change to a 2x2 grid
                app.GridLayout.RowHeight = {539, 539};
                app.GridLayout.ColumnWidth = {'1x', '1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = [1,2];
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 2;
            else
                % Change to a 1x3 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {244, '1x', 431};
                app.LeftPanel.Layout.Row = 1;
                app.LeftPanel.Layout.Column = 1;
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 2;
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 3;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.TestIDLabel = uilabel(app.LeftPanel);
            app.TestIDLabel.Text = 'Test ID';
            app.CreateNewTestButton = uibutton(app.LeftPanel, 'push');
            app.CreateNewTestButton.ButtonPushedFcn = createCallbackFcn(app, @CreateNewTestButtonPushed, true);
            app.CreateNewTestButton.Text = 'Create New Test';
            app.DebugLabeltitle = uilabel(app.LeftPanel);
            app.DebugLabeltitle.Text = 'Debug';
            app.DebugLabel = uilabel(app.LeftPanel);
            app.DebugLabel.Text = 'Default';
            app.CenterPanel = uipanel(app.GridLayout);
            app.ImgAx = uiaxes(app.CenterPanel);
            title(app.ImgAx, 'Taken image')
            app.ColorAX = uiaxes(app.CenterPanel);
            title(app.ColorAX, 'Colour Channels')
            xlabel(app.ColorAX, 'Red | Green | Blue')
            app.ColorAX.XTick = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.CameraModelLabel = uilabel(app.CenterPanel);
            app.CameraModelLabel.Text = 'Camera Model';
            app.SavepictureButton = uibutton(app.CenterPanel, 'push');
            app.SavepictureButton.ButtonPushedFcn = createCallbackFcn(app, @SavepictureButtonPushed, true);
            app.SavepictureButton.Text = 'Save picture';
            app.TakepictureButton = uibutton(app.CenterPanel, 'push');
            app.TakepictureButton.ButtonPushedFcn = createCallbackFcn(app, @TakepictureButtonPushed, true);
            app.TakepictureButton.Text = 'Take picture';
            app.RightPanel = uipanel(app.GridLayout);
            app.ForceSensorAxes = uiaxes(app.RightPanel);
            title(app.ForceSensorAxes, 'Title')
            xlabel(app.ForceSensorAxes, 'X')
            ylabel(app.ForceSensorAxes, 'Y')
            zlabel(app.ForceSensorAxes, 'Z')
            app.ForceSensorAxes.Tag = 'ForceAxis';
            app.saveForceFigButton = uibutton(app.RightPanel, 'push');
            app.saveForceFigButton.ButtonPushedFcn = createCallbackFcn(app, @saveForceFigButtonPushed, true);
            app.saveForceFigButton.Text = 'Save Figure';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Swabbing_test_application

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end